classdef shaft_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ShaftStressAnalysisUIFigure     matlab.ui.Figure
        StressAnalysisTypeSwitch        matlab.ui.control.Switch
        StressAnalysisTypeSwitchLabel   matlab.ui.control.Label
        ModeIIIEditField_2              matlab.ui.control.NumericEditField
        ModeIIIEditField_2Label         matlab.ui.control.Label
        ModeIIEditField_2               matlab.ui.control.NumericEditField
        ModeIIEditField_2Label          matlab.ui.control.Label
        SafetyFactorFractureLabel       matlab.ui.control.Label
        ModeIEditField_2                matlab.ui.control.NumericEditField
        ModeIEditField_2Label           matlab.ui.control.Label
        CriticalFractureToughnessLabel  matlab.ui.control.Label
        ModeIIIEditField                matlab.ui.control.NumericEditField
        ModeIIIEditFieldLabel           matlab.ui.control.Label
        ModeIIEditField                 matlab.ui.control.NumericEditField
        ModeIIEditFieldLabel            matlab.ui.control.Label
        SolveButton                     matlab.ui.control.Button
        ModeIEditField                  matlab.ui.control.NumericEditField
        ModeIEditFieldLabel             matlab.ui.control.Label
        YieldStrengthMPaEditField       matlab.ui.control.NumericEditField
        YieldStrengthMPaEditFieldLabel  matlab.ui.control.Label
        MaterialPropertiesLabel         matlab.ui.control.Label
        SafetyFactorYieldEditField      matlab.ui.control.NumericEditField
        SafetyFactorYieldEditFieldLabel  matlab.ui.control.Label
        DesignContraintsLabel           matlab.ui.control.Label
        LoadingConditionsLabel          matlab.ui.control.Label
        GeometricPropertiesLabel        matlab.ui.control.Label
        BendingMomentEditField          matlab.ui.control.NumericEditField
        BendingMomentEditFieldLabel     matlab.ui.control.Label
        TorqueNmEditField               matlab.ui.control.NumericEditField
        TorqueNmEditFieldLabel          matlab.ui.control.Label
        AxialForceNEditField            matlab.ui.control.NumericEditField
        AxialForceNEditFieldLabel       matlab.ui.control.Label
        LengthmmEditField               matlab.ui.control.NumericEditField
        LengthmmEditFieldLabel          matlab.ui.control.Label
        RadiusmmEditField               matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: SolveButton, TorqueNmEditField
        function SolveButtonPushed(app, event)

            radius = app.RadiusmmEditField.Value;
            length = app.LengthmmEditField.Value;

            axial_force = app.AxialForceNEditField.Value;
            bending_moment = app.BendingMomentEditField.Value;
            torque = app.TorqueNmEditField.Value;

            yield_strength = app.YieldStrengthMPaEditField.Value;
            crit_fracture = app.ModeIEditField.Value;

            safety_yield = app.SafetyFactorYieldEditField.Value;
            safety_fracture = app.ModeIIIEditField_2.Value;


            stresses_result = stressHandle(radius, axial_force, bending_moment, torque);
            eff_stress_result = effHandle(principal_stress,stress_type);
            safety_result = safetyHandle();

            assignin('base','result',stresses_result)
            assignin('base','eff_stress',eff_stress_result)
            assignin('base','safety_factor',safety_result)

            closereq

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ShaftStressAnalysisUIFigure = uifigure('Visible', 'off');
            app.ShaftStressAnalysisUIFigure.Name = 'Shaft Stress Analysis';
            app.Label = uilabel(app.ShaftStressAnalysisUIFigure);
            app.Label.Text = 'Shaft Calculator - Mechanical Behaviour of a Shaft under Combined Loading';
            app.RadiusmmEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.RadiusmmEditFieldLabel.Text = 'Radius (mm)';
            app.RadiusmmEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.LengthmmEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.LengthmmEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.AxialForceNEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.AxialForceNEditFieldLabel.Text = 'Axial Force (N)';
            app.AxialForceNEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.TorqueNmEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.TorqueNmEditFieldLabel.Text = 'Torque (Nm)';
            app.TorqueNmEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.TorqueNmEditField.ValueChangedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.BendingMomentEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.BendingMomentEditFieldLabel.Text = 'Bending Moment';
            app.BendingMomentEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.GeometricPropertiesLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.GeometricPropertiesLabel.Text = 'Geometric Properties';
            app.LoadingConditionsLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.LoadingConditionsLabel.Text = 'Loading Conditions';
            app.DesignContraintsLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.DesignContraintsLabel.Text = 'Design Contraints';
            app.SafetyFactorYieldEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.SafetyFactorYieldEditFieldLabel.Text = 'Safety Factor (Yield)';
            app.SafetyFactorYieldEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.MaterialPropertiesLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.MaterialPropertiesLabel.Text = 'Material Properties';
            app.YieldStrengthMPaEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.YieldStrengthMPaEditFieldLabel.Text = 'Yield Strength (MPa)';
            app.YieldStrengthMPaEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.ModeIEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.ModeIEditFieldLabel.Text = 'Mode I';
            app.ModeIEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.SolveButton = uibutton(app.ShaftStressAnalysisUIFigure, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Text = 'Solve';
            app.ModeIIEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.ModeIIEditFieldLabel.Text = 'Mode II';
            app.ModeIIEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.ModeIIIEditFieldLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.ModeIIIEditFieldLabel.Text = 'Mode III';
            app.ModeIIIEditField = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.CriticalFractureToughnessLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.CriticalFractureToughnessLabel.Text = 'Critical Fracture Toughness';
            app.ModeIEditField_2Label = uilabel(app.ShaftStressAnalysisUIFigure);
            app.ModeIEditField_2Label.Text = 'Mode I';
            app.ModeIEditField_2 = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.SafetyFactorFractureLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.SafetyFactorFractureLabel.Text = 'Safety Factor (Fracture)';
            app.ModeIIEditField_2Label = uilabel(app.ShaftStressAnalysisUIFigure);
            app.ModeIIEditField_2Label.Text = 'Mode II';
            app.ModeIIEditField_2 = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.ModeIIIEditField_2Label = uilabel(app.ShaftStressAnalysisUIFigure);
            app.ModeIIIEditField_2Label.Text = 'Mode III';
            app.ModeIIIEditField_2 = uieditfield(app.ShaftStressAnalysisUIFigure, 'numeric');
            app.StressAnalysisTypeSwitchLabel = uilabel(app.ShaftStressAnalysisUIFigure);
            app.StressAnalysisTypeSwitchLabel.Text = 'Stress Analysis Type';
            app.StressAnalysisTypeSwitch = uiswitch(app.ShaftStressAnalysisUIFigure, 'slider');
            app.StressAnalysisTypeSwitch.Items = {'vonMises', 'Tresca'};
            app.StressAnalysisTypeSwitch.Value = 'vonMises';
            % Show the figure after all components are created
            app.ShaftStressAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shaft_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ShaftStressAnalysisUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ShaftStressAnalysisUIFigure)
        end
    end
end